classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Menu                   matlab.ui.container.Menu
        Menu_2                 matlab.ui.container.Menu
        Menu_3                 matlab.ui.container.Menu
        TabGroup               matlab.ui.container.TabGroup
        Tab                    matlab.ui.container.Tab
        Panel                  matlab.ui.container.Panel
        Spinner_stepfactor     matlab.ui.control.Spinner
        Label_10               matlab.ui.control.Label
        Spinner_popsize        matlab.ui.control.Spinner
        Spinner_2Label_3       matlab.ui.control.Label
        Spinner_gentotal       matlab.ui.control.Spinner
        Label_9                matlab.ui.control.Label
        Spinner_algorithm      matlab.ui.control.DropDown
        Label_8                matlab.ui.control.Label
        Label_11               matlab.ui.control.Label
        Label_6                matlab.ui.control.Label
        Button_StartGen        matlab.ui.control.Button
        Button_RefreshMap      matlab.ui.control.Button
        Button_RefreshMember   matlab.ui.control.Button
        Button_NextGen         matlab.ui.control.Button
        Button_EndGen          matlab.ui.control.Button
        Button_BranchGen       matlab.ui.control.Button
        Button_RefreshGen      matlab.ui.control.Button
        CheckBox_ShowMap       matlab.ui.control.CheckBox
        CheckBox_ShowMember    matlab.ui.control.CheckBox
        CheckBox_ShowGBest     matlab.ui.control.CheckBox
        CheckBox_ShowLegend    matlab.ui.control.CheckBox
        CheckBox_ShowTraj      matlab.ui.control.CheckBox
        CheckBox_ShowPBest     matlab.ui.control.CheckBox
        CheckBox_ShowHBest     matlab.ui.control.CheckBox
        EditField_ShowOnly     matlab.ui.control.NumericEditField
        Slider_Gen             matlab.ui.control.Slider
        Spinner_Gen            matlab.ui.control.Spinner
        Spinner_Speed          matlab.ui.control.Spinner
        EditField_2Label       matlab.ui.control.Label
        SliderLabel            matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
        Tab_2                  matlab.ui.container.Tab
        Panel2                 matlab.ui.container.Panel
        Label_28               matlab.ui.control.Label
        Label_27               matlab.ui.control.Label
        Label_26               matlab.ui.control.Label
        Label_25               matlab.ui.control.Label
        Label_24               matlab.ui.control.Label
        Spinner_c3factor       matlab.ui.control.Spinner
        Label_23               matlab.ui.control.Label
        Spinner_c2factor       matlab.ui.control.Spinner
        Label_22               matlab.ui.control.Label
        Spinner_wfactor        matlab.ui.control.Spinner
        Spinner_2Label_8       matlab.ui.control.Label
        Spinner_c1factor       matlab.ui.control.Spinner
        Label_21               matlab.ui.control.Label
        Spinner_tabulength     matlab.ui.control.Spinner
        Spinner_2Label_7       matlab.ui.control.Label
        Spinner_tabufactor     matlab.ui.control.Spinner
        Label_20               matlab.ui.control.Label
        Spinner_torigin        matlab.ui.control.Spinner
        Spinner_2Label_6       matlab.ui.control.Label
        Spinner_talpha         matlab.ui.control.Spinner
        Label_19               matlab.ui.control.Label
        Spinner_mutatefactor   matlab.ui.control.Spinner
        Label_18               matlab.ui.control.Label
        Spinner_crossfactor    matlab.ui.control.Spinner
        Label_17               matlab.ui.control.Label
        Spinner_pmutate        matlab.ui.control.Spinner
        Label_16               matlab.ui.control.Label
        Spinner_fitnessfactor  matlab.ui.control.Spinner
        Spinner_2Label_5       matlab.ui.control.Label
        Spinner_pcross         matlab.ui.control.Spinner
        Label_15               matlab.ui.control.Label
        Spinner_stepfactor2    matlab.ui.control.Spinner
        Label_14               matlab.ui.control.Label
        Spinner_popsize2       matlab.ui.control.Spinner
        Spinner_2Label_4       matlab.ui.control.Label
        Spinner_gentotal2      matlab.ui.control.Spinner
        Label_13               matlab.ui.control.Label
        Spinner_algorithm2     matlab.ui.control.DropDown
        Label_12               matlab.ui.control.Label
        Tab_3                  matlab.ui.container.Tab
        Panel3                 matlab.ui.container.Panel
        UIAxes_record          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        lower_bdry      % 下界
        upper_bdry      % 上界
        grid_size       % 网格分度值
        grid_amount     % 网格数量

        % 总参数
        algorithm       % 采用的算法
        pop_size        % 个体数量
        gen_total       % 迭代次数
        step_factor     % 步长因子
        step_rate       % 步长下降率
        
        % 遗传算法参数
        fitness_factor  % 适应因子
        p_cross         % 交叉概率
        p_mutate        % 变异概率
        cross_factor    % 交叉因子
        mutate_factor   % 变异因子
        
        % 模拟退火参数
        T_origin        % 初始温度
        T_current       % 当前温度
        T_alpha         % 退温系数
        
        % 禁忌搜索参数
        tabu_table      % 禁忌表
        tabu_length     % 禁忌表长度
        tabu_factor     % 禁忌因子
        
        % 粒子群参数
        w_factor        % 惯性因子
        c1_factor       % 全局最优影响因子
        c2_factor       % 个体最优影响因子
        c3_factor       % 随机扰动影响因子
        pop_pb          % 记录个体历史最优
        pop_gb          % 记录个体历史最优
        
        % 记录用矩阵
        record_pop      % 记录每代各个体的染色体
        record_fitness  % 记录每代各个体的适应值
        record_best     % 记录每代的历史最佳
        best_member     % 记录历史最佳[序号，适应值，坐标]

        % 当前种群信息
        gen
        origin_pop
        this_pop
        last_pop
        origin_v
        this_v
        this_fitness

        % 绘图参数
        X               % X矩阵
        Y               % Y矩阵
        Z               % Z矩阵
        surf_alpha      % 曲面透明度参数
        color           % 每个点的颜色参数
        map_gb          % 最小值
        t               % 计时器
        
    end
    
    methods (Access = public)
        %初始化双变量连续函数
        function init_Map(app)
            n=100;                      % 函数个数
            coef=ones(1,n);             % 系数
            miu1=5*randn(1,n);          % μ1（x平均值）
            miu2=5*randn(1,n);          % μ2（y平均值）
            sigma1=0.5+0.5*rand(1,n);   % σ1（x方差）
            sigma2=0.5+0.5*rand(1,n);   % σ2（y方差）
            rou=1-2*rand(1,n);          % ρ（相关系数）
            posi_rate=0.6;              % 向下的正态分布比例
            %正弦系数函数参数
            sin_A=0.2;                  % 振幅
            sin_omega=6;                % 角频率
            sin_miu1=5*randn(1,n);      % μ1（x平均值）
            sin_miu2=5*randn(1,n);      % μ2（y平均值）
            sin_fai=zeros(1,n);         % φ（相位）
            %网格参数
            app.lower_bdry=-10;         % 下界
            app.upper_bdry=10;          % 上界
            app.grid_size=0.1;          % 网格分度值
            app.grid_amount=0;
            app.grid_amount=(app.upper_bdry-app.lower_bdry)/app.grid_size+1;%网格划分数量（单轴方向）
            % 生成曲面的自变量和因变量数据
            [app.X, app.Y] = meshgrid(app.lower_bdry:app.grid_size:app.upper_bdry);
            %生成正态分布函数组
            A=zeros(n,app.grid_amount,app.grid_amount);
            for i=1:n
                miu=[miu1(i),miu2(i)];
                sigma=[sigma1(i),rou(i)*sigma1(i)*sigma2(i);
                       rou(i)*sigma1(i)*sigma2(i),sigma2(i)];
                app.Z=mvnpdf([app.X(:) app.Y(:)],miu,sigma);  
                app.Z=reshape(app.Z, size(app.Y));              %生成一个正弦函数
                S=(1+sin_A*sin(sin_omega*sqrt( ...
                    (app.X-sin_miu1(i)).^2+(app.Y-sin_miu2(i)).^2+sin_fai(i))));
                if i<n*posi_rate
                    A(i,:,:)=coef(i)*S.*app.Z;          %乘上正弦系数函数
                else
                    A(i,:,:)=-coef(i)*S.*app.Z;
                end
            end
            %生成最终的混合函数
            app.Z=squeeze(sum(A,1));
            [~, linearindex]=min(app.Z(:));
            app.map_gb=zeros(1,2);
            [app.map_gb(2),app.map_gb(1)]=ind2sub(size(app.Z), linearindex);
            app.gen=1;
        end
        %初始化参数
        function init_Params(app)
            %总参数
            app.pop_size=app.Spinner_popsize.Value;        %个体数量
            app.gen_total=app.Spinner_gentotal.Value;      %迭代次数
            app.gen=1;              %当前代数
            app.step_factor=app.Spinner_stepfactor.Value;     %步长因子
            app.step_rate=app.step_factor^(-1/app.gen_total);%步长下降率
            %遗传算法参数
            app.fitness_factor=app.Spinner_fitnessfactor.Value;   %适应因子
            app.p_cross=app.Spinner_pcross.Value;        %交叉概率
            app.p_mutate=app.Spinner_pmutate.Value;       %变异概率
            app.cross_factor=app.Spinner_crossfactor.Value;   %交叉因子
            app.mutate_factor=app.Spinner_mutatefactor.Value;    %变异因子
            %模拟退火参数
            app.T_origin=app.Spinner_torigin.Value;         %初始温度
            app.T_current=app.T_origin; %当前温度
            app.T_alpha=app.Spinner_talpha.Value;       %退温系数
            %T_stop=0.01;       %停止温度（未采用）
            %禁忌搜索参数
            app.tabu_table=zeros(app.grid_amount);%禁忌表
            app.tabu_length=app.Spinner_tabulength.Value;     %禁忌表长度
            app.tabu_factor=app.Spinner_tabufactor.Value;     %禁忌因子（禁忌范围=步长因子*禁忌因子）
            %粒子群参数
            app.w_factor=app.Spinner_wfactor.Value;       %惯性因子
            app.c1_factor=app.Spinner_c1factor.Value;        %全局最优影响因子
            app.c2_factor=app.Spinner_c2factor.Value;      %个体最优影响因子
            app.c3_factor=app.Spinner_c3factor.Value;      %随机扰动影响因子
            app.pop_pb=zeros(app.pop_size,4);%记录个体历史最优
            app.pop_gb=zeros(1,4);  %记录个体历史最优
            %记录用矩阵
            app.record_pop=zeros(app.gen_total,app.pop_size,2);     %记录每代各个体的染色体
            app.record_fitness=zeros(app.gen_total,app.pop_size);   %记录每代各个体的适应值
            app.record_best=zeros(app.gen_total,4);             %记录每代的历史最佳
            app.best_member=[1,0,0,0];                      %记录历史最佳[序号，适应值，坐标]
            
            %绘图参数
            app.surf_alpha=0.3;                             %曲面透明度参数
            app.color=rand(app.pop_size,3);                     %每个点的颜色参数
        end
        %生成初始种群的坐标
        function pop_result=init_Pop(~,pop_size,grid_amount)
            pop_result=randi(grid_amount,pop_size,2);
        end
        %产生初始种群的信息
        function init_Point(app)
            app.gen=1;
            app.origin_pop=init_Pop(app,app.pop_size,app.grid_amount);
            app.this_pop=app.origin_pop; 
            app.this_fitness=calc_Fitness(app,app.this_pop,app.Z,app.fitness_factor);       %计算每个个体适应值
            app.best_member=find_Best(app,app.this_pop,app.this_fitness,app.best_member);   %记录全局历史最佳
            %产生初始速度（粒子群特有）
            app.origin_v=v_Init(app,app.pop_size);
            app.this_v=app.origin_v;
            app.pop_pb=find_Pbest(app,app.this_pop,app.this_fitness,app.pop_pb);    %记录个体历史最佳
            app.pop_gb=app.best_member;                                 %记录全局历史最佳
            %记录信息
            app.record_pop(1,:,:)=app.this_pop;         %记录每代各个体的染色体
            app.record_fitness(1,:)=app.this_fitness;   %记录每代各个体的适应值
            app.record_best(1,:)=app.best_member;       %记录每代的历史最佳
            app.step_factor=app.step_factor*app.step_rate;
            app.gen=2;
        end
        %计算适应值
        function fitness_result=calc_Fitness(~,pop,value_matrix,fitness_factor)
            num=size(pop,1);
            fitness_result=zeros(num,1);
            for i=1:num
                x=pop(i,1);
                y=pop(i,2);
                fitness_result(i)=exp(-fitness_factor*value_matrix(y,x));
            end
        end
        %找出最优个体
        function best_result=find_Best(~,pop,fitness,best)
            num=size(pop,1);
            best_result=best;
            for i=1:num
                if fitness(i)>best(2)
                    best_result=[i,fitness(i),pop(i,:)];
                    best(2)=fitness(i);
                end
            end
        end
        %找出个体历史最优
        function pb_result=find_Pbest(~,pop,fitness,pb)
            num=size(pop,1);
            pb_result=pb;
            for i=1:num
                if fitness(i)>pb(i,2)
                    pb_result(i,:)=[i,fitness(i),pop(i,:)];
                end
            end
        end
        %生成初始速度
        function v_result=v_Init(~,pop_size)
            v_result=zeros(pop_size,2);
            for i=1:pop_size
                theta=2*pi*rand;
                v_result(i,:)=[cos(theta),sin(theta)];
            end
        end
        %更新禁忌表
        function tabu_result=tabu_Update(~,pop,tabu_table,tabu_length,step_factor)
            num=size(pop,1);
            max=size(tabu_table,1);
            tabu_result=tabu_table;
            tabu_result(tabu_result~=0)=tabu_result(tabu_result~=0)-1;%非0元素减一
            for i=1:num
                for m=-round(step_factor):round(step_factor)
                    for n=-round(step_factor):round(step_factor)
                        x=pop(i,1)+m;
                        y=pop(i,2)+n;
                        if  x>0 && x<=max && y>0 && y<=max
                            tabu_result(x,y)=tabu_length;
                        end
                    end
                end
            end
        end
        %种群（轮盘赌）
        function pop_result=pop_Select(~,pop,fitness)
            %选择算子，采用轮盘赌选择方法
            num=size(pop,1);
            total_fitness=0;    %总适应值
            for i=1:num
                 total_fitness=total_fitness+fitness(i);
            end
            pop_result=zeros(num,2);
            pop_tempt=zeros(num,2);
            eval_acc=zeros(num,1);
            eval_acc(1)=fitness(1)/total_fitness;
            %计算累积适应度
            if num>1
                for i=2:num
                    eval_acc(i)=eval_acc(i-1)+fitness(i)/total_fitness;
                end
            end
            for i=1:num
                r=rand;
                for j=1:num
                    if r<eval_acc(j)
                        break;
                    end
                end
                pop_tempt(i,:)=pop(j,:);
            end
            %重分配序号（保留原有位置）
            pop_occupied=zeros(num,1);
            for i=1:num
                for j=1:num
                    if  (pop_tempt(i,:)==pop(j,:))
                        if  (pop_occupied(j)~=1)
                            pop_result(j,:)=pop_tempt(i,:);
                            pop_occupied(j)=1;
                            break;
                        end
                    end
                end
            end
            for i=1:num
                for j=1:num
                    if  pop_occupied(j)==0
                        pop_result(j,:)=pop_tempt(i,:);
                    end
                end
            end
        end
        %种群交叉
        function pop_result=pop_Crossover(~,pop,p_cross,cross_factor)
            num=size(pop,1);
            pop_result=zeros(num,2);
            for i=1:num
                if  rand<p_cross    %交换的情况，父母按给定比例生出孩子
                    father=pop(i,:);
                    mother=pop(randi(num),:);
                    pop_result(i,:)=round(cross_factor*father+(1-cross_factor)*mother);
                else
                    pop_result(i,:)=pop(i,:);
                end
            end
        end
        %种群变异
        function pop_result=pop_Mutation(~,pop,p_mutate,mutate_factor,max)
            num=size(pop,1);
            pop_result=zeros(num,2);
            for i=1:num
                if  rand<p_mutate
                    while(true)
                        theta=2*pi*rand;
                        r=rand;
                        x=pop(i,1)+round(r*mutate_factor*cos(theta));
                        y=pop(i,2)+round(r*mutate_factor*sin(theta));
                        if  x>0 && x<=max && y>0 && y<=max
                            pop_result(i,1)=x;
                            pop_result(i,2)=y;
                            break;
                        end
                    end
                else
                    pop_result(i,:)=pop(i,:);
                end
            end
        end
        %Metropolis准则（模拟退火）
        function pop_result=pop_Metropolis(app,pop,value_matrix,T_current,fitness_factor,step_factor,max)
            num=size(pop,1);
            pop_result=zeros(num,2);
            for i=1:num
                while(true)
                    theta=2*pi*rand;
                    x=pop(i,1)+round(rand*step_factor*cos(theta));
                    y=pop(i,2)+round(rand*step_factor*sin(theta));
                    if  x>0 && x<=max && y>0 && y<=max
                        crrt_fitness=calc_Fitness(app,pop(i,:),value_matrix,fitness_factor);
                        temp_fitness=calc_Fitness(app,[x,y],value_matrix,fitness_factor);
                        delta_energy=crrt_fitness-temp_fitness;
                        if  delta_energy<=0
                            pop_result(i,:)=[x,y];
                            break;
                        elseif rand<exp(-delta_energy/T_current)
                            pop_result(i,:)=[x,y];
                            break;
                        end
                    end
                end
            end
        end
        %邻域搜索
        function pop_result=pop_Localsearch(app,pop,value_matrix,fitness_factor,step_factor,max)
            num=size(pop,1);
            pop_result=zeros(num,2);
            for i=1:num
                best_fitness=calc_Fitness(app,pop(i,:),value_matrix,fitness_factor);
                best=pop(i,:);
                for m=-1:1
                    for n=-1:1
                        x=pop(i,1)+round(step_factor*m);
                        y=pop(i,2)+round(step_factor*n);
                        if  x>0 && x<=max && y>0 && y<=max
                            temp_fitness=calc_Fitness(app,[x,y],value_matrix,fitness_factor);
                            if  temp_fitness>best_fitness
                                best_fitness=temp_fitness;
                                best=[x,y];
                            end
                        end
                    end
                end
                pop_result(i,:)=best;
            end
        end
        %禁忌搜索
        function pop_result=pop_Tabusearch(app,pop,tabu_table,value_matrix,fitness_factor,step_factor)
            num=size(pop,1);
            max=size(tabu_table,1);
            pop_result=zeros(num,2);
            for i=1:num
                best_fitness=0;
                best=[0,0];
                for m=-1:1
                    for n=-1:1
                        x=pop(i,1)+round(step_factor*m);
                        y=pop(i,2)+round(step_factor*n);
                        if  x>0 && x<=max && y>0 && y<=max 
                            if  tabu_table(x,y)==0
                                temp_fitness=calc_Fitness(app,[x,y],value_matrix,fitness_factor);
                                if  temp_fitness>best_fitness
                                    best_fitness=temp_fitness;
                                    best=[x,y];
                                end
                            end
                        end
                    end
                end
                if  best_fitness==0
                    pop_result(i,:)=pop(i,:);
                else
                    pop_result(i,:)=best;
                end
            end
        end
        %粒子群更新速度位置信息
        function [x_result,v_result]=pop_Vupdate(app,x,v,pop_gb,pop_pb,w,c1,c2,c3,step_factor,max)
            num=size(x,1);
            x_gb=pop_gb(3:4);
            x_pb=pop_pb(:,3:4);
            v1=zeros(num,2);
            v2=zeros(num,2);
            v3=v_Init(app,num);            %随机向量
            for i=1:num
                if  x_gb(1,:)-x(i,:)~=zeros(1,2)
                    v1(i,:)=(x_gb(1,:)-x(i,:))/norm(x_gb(1,:)-x(i,:));
                end
                if  x_pb(i,:)-x(i,:)~=zeros(1,2)
                    v2(i,:)=(x_pb(i,:)-x(i,:))/norm(x_pb(i,:)-x(i,:));
                end
            end
            v_result=w*v+c1*rand*v1+c2*rand*v2+c3*rand*v3;
            x_result=x+round(step_factor*v_result);
            for i=1:num
                if  x_result(i,1)<=0
                    x_result(i,1)=1;
                end
                if  x_result(i,1)>max
                    x_result(i,1)=max;
                end
                if  x_result(i,2)<=0
                    x_result(i,2)=1;
                end
                if  x_result(i,2)>max
                    x_result(i,2)=max;
                end
            end
        end
        %绘制函数
        function draw_Map(app,X,Y,Z,tabu_table,surf_alpha)
            [caz,cel]=view(app.UIAxes);
            alphaValues=surf_alpha*2.^(-tabu_table);
            alphaValues=alphaValues';
            % 离散化透明度数组
            numLevels=100;  % 透明度级别数量
            thresholds=linspace(0, 1, numLevels + 1);  % 阈值数组
            alphaDiscrete=discretize(alphaValues, thresholds);  % 离散化透明度数组
            % 绘制曲面
            if  app.CheckBox_ShowMap.Value==1
                surf(app.UIAxes,X,Y,Z,'MeshStyle','both','FaceColor','interp', ...
                    'FaceAlpha','interp','AlphaData',alphaDiscrete, ...
                    'AlphaDataMapping','direct','HandleVisibility', 'off');
            else
                cla(app.UIAxes,'reset');
                surf(app.UIAxes,X,Y,Z,'MeshStyle','none','FaceAlpha',0, ...
                    'HandleVisibility', 'off');
            end
                colormap(app.UIAxes,'turbo');  % 设置 colormap 为 "turbo"
                colorbar(app.UIAxes);  % 显示颜色条
                % 启用 rotate3d 功能
                rotate3d(app.UIAxes,'on');
                % 添加标题和坐标轴标签
                title(app.UIAxes,'双变量函数连续优化');
                xlabel(app.UIAxes,'X');
                ylabel(app.UIAxes,'Y');
                zlabel(app.UIAxes,'Z');
                hold(app.UIAxes,'on');
                view(app.UIAxes,[caz,cel]);
        end
        %绘制个体
        function draw_Point(app,X,Y,Z,pop,color)
            num=size(pop,1);
            [caz,cel]=view(app.UIAxes);
            if  app.CheckBox_ShowMember.Value==1
                 for i=1:num
                    osize=200;
                    marktype='o';
                    x=X(1,pop(i,1));
                    y=Y(pop(i,2),1);
                    z=Z(pop(i,2),pop(i,1));
                    if  app.EditField_ShowOnly.Value==0 || i==app.EditField_ShowOnly.Value
                        scatter3(app.UIAxes,x,y,z,osize,color(i,:),'filled', ...
                            marktype,'DisplayName',['个体',num2str(i)]);
                    end
                 end
            end
            if  app.CheckBox_ShowLegend.Value==1 && num<=20
                legend(app.UIAxes,'show');
            end
            view(app.UIAxes,[caz,cel]);
        end
        %绘制最优点
        function draw_Best(app)
            if  app.CheckBox_ShowPBest.Value==1 && app.pop_gb(2)~=0 %显示个体历史最优
                 for i=1:app.pop_size
                    x=app.X(1,app.pop_pb(i,3));
                    y=app.Y(app.pop_pb(i,4),1);
                    z=app.Z(app.pop_pb(i,4),app.pop_pb(i,3));
                    if  app.EditField_ShowOnly.Value==0 || i==app.EditField_ShowOnly.Value
                        scatter3(app.UIAxes,x,y,z,100,app.color(i,:),'filled', ...
                            'diamond','HandleVisibility', 'off');
                    end
                 end
            end
            if  app.CheckBox_ShowHBest.Value==1 && app.pop_gb(2)~=0 %显示历史最优
                x=app.X(1,app.pop_gb(3));
                y=app.Y(app.pop_gb(4),1);
                z=app.Z(app.pop_gb(4),app.pop_gb(3));
                scatter3(app.UIAxes,x,y,z,300,[0,0,0],'filled', ...
                        'pentagram','DisplayName','历史最优');
            end
            if  app.CheckBox_ShowGBest.Value==1 %显示全局最优
                x=app.X(1,app.map_gb(1));
                y=app.Y(app.map_gb(2),1);
                z=app.Z(app.map_gb(2),app.map_gb(1));
                scatter3(app.UIAxes,x,y,z,300,[0,0,0],'filled', ...
                        'hexagram','DisplayName','全局最优');
            end
        end
        %显示个体轨迹
        function draw_Traj(app,X,Y,Z,pop,color)
            if  app.CheckBox_ShowTraj.Value==1
                for i=1:app.pop_size
                    if  app.gen>2
                        for j=max(2,app.gen-10):app.gen-1
                            x=[X(1,pop(j,i,1)),X(1,pop(j-1,i,1))];
                            y=[Y(pop(j,i,2),1),Y(pop(j-1,i,2),1)];
                            z=[Z(pop(j,i,2),pop(j,i,1)),Z(pop(j-1,i,2),pop(j-1,i,1))];
                            if  app.EditField_ShowOnly.Value==0 || i==app.EditField_ShowOnly.Value
                                plot3(app.UIAxes,x,y,z,'-','Color',color(i,:), ...
                                    'Marker','.','HandleVisibility', 'off');
                            end
                        end
                    end
                end
            end
        end
        %绘制记录
        function draw_Record(app)
            minimum=min(app.Z(:))*ones(app.gen_total,1);
            record_value=-log(app.record_fitness)/app.fitness_factor;
            best_value=-log(app.record_best(:,2))/app.fitness_factor;
            cla(app.UIAxes_record,'reset');
            x=1:app.gen_total;            
            hold(app.UIAxes_record,'on');
            title(app.UIAxes_record,'个体函数值与最小值');
            plot(app.UIAxes_record,x,best_value,'b',x,best_value);
            plot(app.UIAxes_record,x,minimum,'r',x,minimum);
            plot(app.UIAxes_record,x,record_value,'g',x,record_value);
        end
        %重绘坐标区
        function draw_UIAxes(app)
            [caz,cel]=view(app.UIAxes);
            cla(app.UIAxes,'reset');
            view(app.UIAxes,[caz,cel]);
            draw_Map(app,app.X,app.Y,app.Z,app.tabu_table,app.surf_alpha);
            draw_Point(app,app.X,app.Y,app.Z,app.this_pop,app.color);
            draw_Best(app);
            draw_Traj(app,app.X,app.Y,app.Z,app.record_pop,app.color)
            app.Spinner_Gen.Value=max(app.gen-1,1);
            view(app.UIAxes,[caz,cel]);
        end
        %自动迭代（用不了）
        function auto_Gen(app)
            app.t=timer;
            app.t.StartDelay=5;
            app.t.Period=5;
            app.t.ExecutionMode='fixedRate'; % 固定频率执行模式
            app.t.TimerFcn=@(~,~)next_Gen(app);
            start(app.t);
        end
        %迭代一次
        function next_Gen(app)
            if  app.gen<=app.gen_total
                switch app.algorithm
                    case 'GA'
                        GA(app);
                    case 'SA'
                        SA(app);
                    case 'HC'
                        HC(app);
                    case 'TS'
                        TS(app);
                    case 'PSO'
                        PSO(app);
                    case 'MC'
                        MC(app);
                end
            end
            draw_UIAxes(app);
        end
        %遗传算法
        function GA(app)
            app.last_pop=app.this_pop;
            %选择操作
            app.this_pop=pop_Select(app,app.this_pop,app.this_fitness);
            %检查点
            app.this_fitness=calc_Fitness(app,app.this_pop,app.Z,app.fitness_factor);
            app.best_member=find_Best(app,app.this_pop,app.this_fitness,app.best_member);
            %交叉操作
            app.this_pop=pop_Crossover(app,app.this_pop,app.p_cross,app.cross_factor);
            %检查点
            app.this_fitness=calc_Fitness(app,app.this_pop,app.Z,app.fitness_factor);
            app.best_member=find_Best(app,app.this_pop,app.this_fitness,app.best_member);
            %变异操作
            app.this_pop=pop_Mutation(app,app.this_pop,app.p_mutate,app.mutate_factor,app.grid_amount);
            %检查点
            app.this_fitness=calc_Fitness(app,app.this_pop,app.Z,app.fitness_factor);
            app.best_member=find_Best(app,app.this_pop,app.this_fitness,app.best_member);
            %更新全局与个体最优位置信息
            app.pop_gb=app.best_member;
            app.pop_pb=find_Pbest(app,app.this_pop,app.this_fitness,app.pop_pb);
            %记录信息
            app.record_pop(app.gen,:,:)=app.this_pop;
            app.record_fitness(app.gen,:)=app.this_fitness;
            app.record_best(app.gen)=app.best_member(2);
            app.gen=app.gen+1;
        end
        %模拟退火
        function SA(app)
            app.last_pop=app.this_pop;
            app.step_factor=app.step_factor*app.step_rate;
            %metropolis准则
            app.this_pop=pop_Metropolis(app,app.this_pop,app.Z,app.T_current, ...
                app.fitness_factor,app.step_factor,app.grid_amount);
            %检查点
            app.this_fitness=calc_Fitness(app,app.this_pop,app.Z,app.fitness_factor);
            app.best_member=find_Best(app,app.this_pop,app.this_fitness,app.best_member);
            %更新全局与个体最优位置信息
            app.pop_gb=app.best_member;
            app.pop_pb=find_Pbest(app,app.this_pop,app.this_fitness,app.pop_pb);
            %降温
            app.T_current=app.T_alpha*app.T_current;
            %记录信息
            app.record_pop(app.gen,:,:)=app.this_pop;
            app.record_fitness(app.gen,:)=app.this_fitness;
            app.record_best(app.gen,:)=app.best_member;
            app.gen=app.gen+1;
        end
        %贪心算法
        function HC(app)
            app.last_pop=app.this_pop;
            app.step_factor=app.step_factor*app.step_rate;
            %寻找邻域最优解
            app.this_pop=pop_Localsearch(app,app.this_pop,app.Z, ...
                app.fitness_factor,app.step_factor,app.grid_amount);
            %检查点
            app.this_fitness=calc_Fitness(app,app.this_pop,app.Z,app.fitness_factor);
            app.best_member=find_Best(app,app.this_pop,app.this_fitness,app.best_member);
            %更新全局与个体最优位置信息
            app.pop_gb=app.best_member;
            app.pop_pb=find_Pbest(app,app.this_pop,app.this_fitness,app.pop_pb);
            %记录数据
            app.record_pop(app.gen,:,:)=app.this_pop;
            app.record_fitness(app.gen,:)=app.this_fitness;
            app.record_best(app.gen,:)=app.best_member;
            app.gen=app.gen+1;
        end
        %禁忌搜索
        function TS(app)
            app.last_pop=app.this_pop;
            app.step_factor=app.step_factor*app.step_rate;
            %更新禁忌表
            app.tabu_table=tabu_Update(app,app.this_pop,app.tabu_table, ...
                app.tabu_length,app.step_factor*app.tabu_factor);
            %禁忌搜索
            app.this_pop=pop_Tabusearch(app,app.this_pop,app.tabu_table,app.Z, ...
                app.fitness_factor,app.step_factor);
            %检查点
            app.this_fitness=calc_Fitness(app,app.this_pop,app.Z,app.fitness_factor);
            app.best_member=find_Best(app,app.this_pop,app.this_fitness,app.best_member);
            %更新全局与个体最优位置信息
            app.pop_gb=app.best_member;
            app.pop_pb=find_Pbest(app,app.this_pop,app.this_fitness,app.pop_pb);
            %记录数据
            app.record_pop(app.gen,:,:)=app.this_pop;
            app.record_fitness(app.gen,:)=app.this_fitness;
            app.record_best(app.gen,:)=app.best_member;
            app.gen=app.gen+1;
        end
        %粒子群算法
        function PSO(app)
            app.last_pop=app.this_pop;
            app.step_factor=app.step_factor*app.step_rate;
            %更新位置与速度
            [app.this_pop,app.this_v]=pop_Vupdate(app,app.this_pop,app.this_v, ...
                app.pop_gb,app.pop_pb,app.w_factor,app.c1_factor, ...
                app.c2_factor,app.c3_factor,app.step_factor,app.grid_amount);
            %检查点
            app.this_fitness=calc_Fitness(app,app.this_pop,app.Z,app.fitness_factor);
            app.best_member=find_Best(app,app.this_pop,app.this_fitness,app.best_member);
            %更新全局与个体最优位置信息
            app.pop_gb=app.best_member;
            app.pop_pb=find_Pbest(app,app.this_pop,app.this_fitness,app.pop_pb);
            %记录数据
            app.record_pop(app.gen,:,:)=app.this_pop;
            app.record_fitness(app.gen,:)=app.this_fitness;
            app.record_best(app.gen,:)=app.best_member;
            app.gen=app.gen+1;
        end
        %蒙特卡洛方法
        function MC(app)
            app.last_pop=app.this_pop;
            app.step_factor=app.step_factor*app.step_rate;
            %完全随机搜索
            app.this_pop=init_Pop(app,app.pop_size,app.grid_amount);
            %检查点
            app.this_fitness=calc_Fitness(app,app.this_pop,app.Z,app.fitness_factor);
            app.best_member=find_Best(app,app.this_pop,app.this_fitness,app.best_member);
            %更新全局与个体最优位置信息
            app.pop_gb=app.best_member;
            app.pop_pb=find_Pbest(app,app.this_pop,app.this_fitness,app.pop_pb);
            %记录数据
            app.record_pop(app.gen,:,:)=app.this_pop;
            app.record_fitness(app.gen,:)=app.this_fitness;
            app.record_best(app.gen,:)=app.best_member;
            app.gen=app.gen+1;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.algorithm='none';   %采用算法
            init_Map(app);
            init_Params(app);
            init_Point(app);
            draw_Map(app,app.X,app.Y,app.Z,app.tabu_table,app.surf_alpha);         %绘制曲面
        end

        % Callback function
        function Spinner_algorithmValueChanged(app, event)
            value = app.Spinner_algorithm.Value;
            switch value
                case '无'
                    app.algorithm='none';
                case '遗传算法'
                    app.algorithm='GA';
                case '模拟退火'
                    app.algorithm='SA';
                case '贪心算法'
                    app.algorithm='HC';
                case '禁忌搜索'
                    app.algorithm='TS';
                case '粒子群算法'
                    app.algorithm='PSO';
                case '蒙特卡洛'
                    app.algorithm='MC';
            end
        end

        % Button pushed function: Button_EndGen
        function Button_EndGenPushed(app, event)
            switch app.algorithm
                case 'GA'   %遗传算法
                    while(app.gen<=app.gen_total)
                        GA(app);
                    end
                case 'SA'   %模拟退火
                    while(app.gen<=app.gen_total)
                        SA(app);
                    end
                case 'HC'   %贪心算法
                    while(app.gen<=app.gen_total)
                        HC(app);
                    end
                case 'TS'   %禁忌搜索
                    while(app.gen<=app.gen_total)
                        TS(app);
                    end
                case 'PSO'  %粒子群算法
                    while(app.gen<=app.gen_total)
                        PSO(app);
                    end
                case 'MC'   %蒙特卡洛方法
                    while(app.gen<=app.gen_total)
                        MC(app);
                    end
            end
            draw_UIAxes(app);
            draw_Record(app);
        end

        % Button pushed function: Button_NextGen
        function Button_NextGenPushed(app, event)
            if  app.gen<=app.gen_total
                switch app.algorithm
                    case 'GA'
                        GA(app);
                    case 'SA'
                        SA(app);
                    case 'HC'
                        HC(app);
                    case 'TS'
                        TS(app);
                    case 'PSO'
                        PSO(app);
                    case 'MC'
                        MC(app);
                end
            end
            draw_UIAxes(app);
            
%            Update_appparams(app);
        end

        % Button pushed function: Button_RefreshMember
        function Button_RefreshMemberPushed(app, event)
            init_Point(app);
            draw_UIAxes(app);
        end

        % Button pushed function: Button_RefreshMap
        function Button_RefreshMapPushed(app, event)
            init_Map(app);
            init_Params(app);
            draw_UIAxes(app);
        end

        % Value changed function: CheckBox_ShowMap
        function CheckBox_ShowMapValueChanged(app, event)
            draw_UIAxes(app);
        end

        % Value changed function: CheckBox_ShowMember
        function CheckBox_ShowMemberValueChanged(app, event)
            draw_UIAxes(app);
        end

        % Button pushed function: Button_RefreshGen
        function Button_RefreshGenPushed(app, event)
            init_Params(app);
        end

        % Value changed function: Spinner_algorithm
        function Spinner_algorithmValueChanged2(app, event)
            value = app.Spinner_algorithm.Value;
            switch value
                case '无'
                    app.algorithm='none';
                case '遗传算法'
                    app.algorithm='GA';
                case '模拟退火'
                    app.algorithm='SA';
                case '贪心算法'
                    app.algorithm='HC';
                case '禁忌搜索'
                    app.algorithm='TS';
                case '粒子群算法'
                    app.algorithm='PSO';
                case '蒙特卡洛'
                    app.algorithm='MC';
            end
        end

        % Value changed function: CheckBox_ShowLegend
        function CheckBox_ShowLegendValueChanged(app, event)
            draw_UIAxes(app);
        end

        % Value changed function: CheckBox_ShowGBest
        function CheckBox_ShowGBestValueChanged(app, event)
            draw_UIAxes(app);
        end

        % Value changed function: CheckBox_ShowHBest
        function CheckBox_ShowHBestValueChanged(app, event)
            draw_UIAxes(app);
        end

        % Value changed function: CheckBox_ShowPBest
        function CheckBox_ShowPBestValueChanged(app, event)
            draw_UIAxes(app);
        end

        % Value changed function: CheckBox_ShowTraj
        function CheckBox_ShowTrajValueChanged(app, event)
            draw_UIAxes(app);
        end

        % Value changed function: EditField_ShowOnly
        function EditField_ShowOnlyValueChanged(app, event)
            if  app.EditField_ShowOnly.Value<0 || app.EditField_ShowOnly.Value>app.pop_size
                app.EditField_ShowOnly.Value=0;
            end
            draw_UIAxes(app);
        end

        % Value changing function: Slider_Gen
        function Slider_GenValueChanging(app, event)

        end

        % Value changed function: Slider_Gen
        function Slider_GenValueChanged(app, event)

        end

        % Value changed function: Spinner_Gen
        function Spinner_GenValueChanged(app, event)
            if  app.Spinner_Gen.Value<=0 
                app.Spinner_Gen.Value=1;
            end
            if  app.Spinner_Gen.Value>=app.gen_total
                app.Spinner_Gen.Value=app.gen_total-1;
            end
            draw_UIAxes(app);
        end

        % Button pushed function: Button_StartGen
        function Button_StartGenPushed(app, event)
            %auto_Gen(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '曲面参数';
            app.Menu_2 = uimenu(app.UIFigure);
            app.Menu_2.Text = '绘图参数';
            app.Menu_3 = uimenu(app.Menu_2);
            app.Menu_3.Text = 'Menu';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '曲面';
            app.Panel = uipanel(app.Tab);
            app.Panel.Title = 'Panel';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SliderLabel = uilabel(app.Panel);
            app.SliderLabel.Text = '迭代次数';
            app.EditField_2Label = uilabel(app.Panel);
            app.EditField_2Label.Text = '只显示个体';
            app.Spinner_Speed = uispinner(app.Panel);
            app.Spinner_Gen = uispinner(app.Panel);
            app.Spinner_Gen.Limits = [1 Inf];
            app.Spinner_Gen.ValueChangedFcn = createCallbackFcn(app, @Spinner_GenValueChanged, true);
            app.Spinner_Gen.Value = 1;
            app.Slider_Gen = uislider(app.Panel);
            app.Slider_Gen.Limits = [0 10];
            app.Slider_Gen.ValueChangedFcn = createCallbackFcn(app, @Slider_GenValueChanged, true);
            app.Slider_Gen.ValueChangingFcn = createCallbackFcn(app, @Slider_GenValueChanging, true);
            app.EditField_ShowOnly = uieditfield(app.Panel, 'numeric');
            app.EditField_ShowOnly.ValueChangedFcn = createCallbackFcn(app, @EditField_ShowOnlyValueChanged, true);
            app.CheckBox_ShowHBest = uicheckbox(app.Panel);
            app.CheckBox_ShowHBest.ValueChangedFcn = createCallbackFcn(app, @CheckBox_ShowHBestValueChanged, true);
            app.CheckBox_ShowHBest.Text = '显示历史最佳点';
            app.CheckBox_ShowPBest = uicheckbox(app.Panel);
            app.CheckBox_ShowPBest.ValueChangedFcn = createCallbackFcn(app, @CheckBox_ShowPBestValueChanged, true);
            app.CheckBox_ShowPBest.Text = '显示个体最佳点';
            app.CheckBox_ShowTraj = uicheckbox(app.Panel);
            app.CheckBox_ShowTraj.ValueChangedFcn = createCallbackFcn(app, @CheckBox_ShowTrajValueChanged, true);
            app.CheckBox_ShowTraj.Text = '显示个体轨迹';
            app.CheckBox_ShowLegend = uicheckbox(app.Panel);
            app.CheckBox_ShowLegend.ValueChangedFcn = createCallbackFcn(app, @CheckBox_ShowLegendValueChanged, true);
            app.CheckBox_ShowLegend.Text = '显示图例';
            app.CheckBox_ShowGBest = uicheckbox(app.Panel);
            app.CheckBox_ShowGBest.ValueChangedFcn = createCallbackFcn(app, @CheckBox_ShowGBestValueChanged, true);
            app.CheckBox_ShowGBest.Text = '显示全局最佳点';
            app.CheckBox_ShowMember = uicheckbox(app.Panel);
            app.CheckBox_ShowMember.ValueChangedFcn = createCallbackFcn(app, @CheckBox_ShowMemberValueChanged, true);
            app.CheckBox_ShowMember.Text = '显示个体';
            app.CheckBox_ShowMap = uicheckbox(app.Panel);
            app.CheckBox_ShowMap.ValueChangedFcn = createCallbackFcn(app, @CheckBox_ShowMapValueChanged, true);
            app.CheckBox_ShowMap.Text = '显示地图';
            app.Button_RefreshGen = uibutton(app.Panel, 'push');
            app.Button_RefreshGen.ButtonPushedFcn = createCallbackFcn(app, @Button_RefreshGenPushed, true);
            app.Button_RefreshGen.Text = '刷新迭代';
            app.Button_BranchGen = uibutton(app.Panel, 'push');
            app.Button_BranchGen.Text = '分支迭代';
            app.Button_EndGen = uibutton(app.Panel, 'push');
            app.Button_EndGen.ButtonPushedFcn = createCallbackFcn(app, @Button_EndGenPushed, true);
            app.Button_EndGen.Text = '全速迭代';
            app.Button_NextGen = uibutton(app.Panel, 'push');
            app.Button_NextGen.ButtonPushedFcn = createCallbackFcn(app, @Button_NextGenPushed, true);
            app.Button_NextGen.Text = '单次迭代';
            app.Button_RefreshMember = uibutton(app.Panel, 'push');
            app.Button_RefreshMember.ButtonPushedFcn = createCallbackFcn(app, @Button_RefreshMemberPushed, true);
            app.Button_RefreshMember.Text = '刷新个体';
            app.Button_RefreshMap = uibutton(app.Panel, 'push');
            app.Button_RefreshMap.ButtonPushedFcn = createCallbackFcn(app, @Button_RefreshMapPushed, true);
            app.Button_RefreshMap.Text = '刷新地图';
            app.Button_StartGen = uibutton(app.Panel, 'push');
            app.Button_StartGen.ButtonPushedFcn = createCallbackFcn(app, @Button_StartGenPushed, true);
            app.Button_StartGen.Text = '开始迭代';
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = '速度';
            app.Label_11 = uilabel(app.Panel);
            app.Label_11.Text = '迭代次数';
            app.Label_8 = uilabel(app.Panel);
            app.Label_8.Text = '算法选择';
            app.Spinner_algorithm = uidropdown(app.Panel);
            app.Spinner_algorithm.Items = {'无', '遗传算法', '模拟退火', '贪心算法', '禁忌搜索', '粒子群算法', '蒙特卡洛'};
            app.Spinner_algorithm.ValueChangedFcn = createCallbackFcn(app, @Spinner_algorithmValueChanged2, true);
            app.Spinner_algorithm.Value = '无';
            app.Label_9 = uilabel(app.Panel);
            app.Label_9.Text = '迭代次数';
            app.Spinner_gentotal = uispinner(app.Panel);
            app.Spinner_gentotal.Limits = [1 Inf];
            app.Spinner_gentotal.Value = 100;
            app.Spinner_2Label_3 = uilabel(app.Panel);
            app.Spinner_2Label_3.Text = '个体数量';
            app.Spinner_popsize = uispinner(app.Panel);
            app.Spinner_popsize.Limits = [1 Inf];
            app.Spinner_popsize.Value = 10;
            app.Label_10 = uilabel(app.Panel);
            app.Label_10.Text = '步长因子';
            app.Spinner_stepfactor = uispinner(app.Panel);
            app.Spinner_stepfactor.Limits = [0 Inf];
            app.Spinner_stepfactor.RoundFractionalValues = 'on';
            app.Spinner_stepfactor.Value = 10;
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '参数';
            app.Panel2 = uipanel(app.Tab_2);
            app.Panel2.Title = 'Panel2';
            app.Label_12 = uilabel(app.Panel2);
            app.Label_12.Text = '算法选择';
            app.Spinner_algorithm2 = uidropdown(app.Panel2);
            app.Spinner_algorithm2.Items = {'无', '遗传算法', '模拟退火', '贪心算法', '禁忌搜索', '粒子群算法', '蒙特卡洛'};
            app.Spinner_algorithm2.Value = '无';
            app.Label_13 = uilabel(app.Panel2);
            app.Label_13.Text = '迭代次数';
            app.Spinner_gentotal2 = uispinner(app.Panel2);
            app.Spinner_gentotal2.Limits = [1 Inf];
            app.Spinner_gentotal2.Value = 1;
            app.Spinner_2Label_4 = uilabel(app.Panel2);
            app.Spinner_2Label_4.Text = '个体数量';
            app.Spinner_popsize2 = uispinner(app.Panel2);
            app.Spinner_popsize2.Limits = [1 Inf];
            app.Spinner_popsize2.Value = 1;
            app.Label_14 = uilabel(app.Panel2);
            app.Label_14.Text = '步长因子';
            app.Spinner_stepfactor2 = uispinner(app.Panel2);
            app.Spinner_stepfactor2.Limits = [0 Inf];
            app.Label_15 = uilabel(app.Panel2);
            app.Label_15.Text = '交叉概率';
            app.Spinner_pcross = uispinner(app.Panel2);
            app.Spinner_pcross.Step = 0.01;
            app.Spinner_pcross.Limits = [0 1];
            app.Spinner_pcross.Value = 0.8;
            app.Spinner_2Label_5 = uilabel(app.Panel2);
            app.Spinner_2Label_5.Text = '适应因子';
            app.Spinner_fitnessfactor = uispinner(app.Panel2);
            app.Spinner_fitnessfactor.Limits = [1 Inf];
            app.Spinner_fitnessfactor.Value = 1;
            app.Label_16 = uilabel(app.Panel2);
            app.Label_16.Text = '变异概率';
            app.Spinner_pmutate = uispinner(app.Panel2);
            app.Spinner_pmutate.Step = 0.01;
            app.Spinner_pmutate.Limits = [0 1];
            app.Spinner_pmutate.Value = 0.5;
            app.Label_17 = uilabel(app.Panel2);
            app.Label_17.Text = '交叉因子';
            app.Spinner_crossfactor = uispinner(app.Panel2);
            app.Spinner_crossfactor.Step = 0.01;
            app.Spinner_crossfactor.Limits = [0 1];
            app.Spinner_crossfactor.Value = 0.9;
            app.Label_18 = uilabel(app.Panel2);
            app.Label_18.Text = '变异步长';
            app.Spinner_mutatefactor = uispinner(app.Panel2);
            app.Spinner_mutatefactor.Step = 0.5;
            app.Spinner_mutatefactor.Limits = [0 Inf];
            app.Spinner_mutatefactor.Value = 3;
            app.Label_19 = uilabel(app.Panel2);
            app.Label_19.Text = '退温系数';
            app.Spinner_talpha = uispinner(app.Panel2);
            app.Spinner_talpha.Step = 0.01;
            app.Spinner_talpha.Limits = [0 1];
            app.Spinner_talpha.Value = 0.95;
            app.Spinner_2Label_6 = uilabel(app.Panel2);
            app.Spinner_2Label_6.Text = '初始温度';
            app.Spinner_torigin = uispinner(app.Panel2);
            app.Spinner_torigin.Limits = [0 Inf];
            app.Spinner_torigin.Value = 1;
            app.Label_20 = uilabel(app.Panel2);
            app.Label_20.Text = '禁忌因子';
            app.Spinner_tabufactor = uispinner(app.Panel2);
            app.Spinner_tabufactor.Step = 0.01;
            app.Spinner_tabufactor.Limits = [0 1];
            app.Spinner_tabufactor.Value = 0.5;
            app.Spinner_2Label_7 = uilabel(app.Panel2);
            app.Spinner_2Label_7.Text = '禁忌长度';
            app.Spinner_tabulength = uispinner(app.Panel2);
            app.Spinner_tabulength.Limits = [1 Inf];
            app.Spinner_tabulength.RoundFractionalValues = 'on';
            app.Spinner_tabulength.Value = 10;
            app.Label_21 = uilabel(app.Panel2);
            app.Label_21.Text = '全局最佳影响因子';
            app.Spinner_c1factor = uispinner(app.Panel2);
            app.Spinner_c1factor.Step = 0.01;
            app.Spinner_c1factor.Limits = [0 1];
            app.Spinner_c1factor.Value = 0.5;
            app.Spinner_2Label_8 = uilabel(app.Panel2);
            app.Spinner_2Label_8.Text = '惯性因子';
            app.Spinner_wfactor = uispinner(app.Panel2);
            app.Spinner_wfactor.Step = 0.01;
            app.Spinner_wfactor.Limits = [0 1];
            app.Spinner_wfactor.Value = 0.2;
            app.Label_22 = uilabel(app.Panel2);
            app.Label_22.Text = '个体最佳影响因子';
            app.Spinner_c2factor = uispinner(app.Panel2);
            app.Spinner_c2factor.Step = 0.01;
            app.Spinner_c2factor.Limits = [0 1];
            app.Spinner_c2factor.Value = 0.2;
            app.Label_23 = uilabel(app.Panel2);
            app.Label_23.Text = '随机扰动影响因子';
            app.Spinner_c3factor = uispinner(app.Panel2);
            app.Spinner_c3factor.Step = 0.01;
            app.Spinner_c3factor.Limits = [0 1];
            app.Spinner_c3factor.Value = 0.1;
            app.Label_24 = uilabel(app.Panel2);
            app.Label_24.Text = '全局参数';
            app.Label_25 = uilabel(app.Panel2);
            app.Label_25.Text = '遗传算法参数';
            app.Label_26 = uilabel(app.Panel2);
            app.Label_26.Text = '模拟退火参数';
            app.Label_27 = uilabel(app.Panel2);
            app.Label_27.Text = '禁忌搜索参数';
            app.Label_28 = uilabel(app.Panel2);
            app.Label_28.Text = '粒子群算法参数';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '最小值';
            app.Panel3 = uipanel(app.Tab_3);
            app.Panel3.Title = 'Panel3';
            app.UIAxes_record = uiaxes(app.Panel3);
            title(app.UIAxes_record, 'Title')
            xlabel(app.UIAxes_record, 'X')
            ylabel(app.UIAxes_record, 'Y')
            zlabel(app.UIAxes_record, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end